classdef SBTimer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Timer                         matlab.ui.Figure
        Tabs                          matlab.ui.container.TabGroup
        Create                        matlab.ui.container.Tab
        Selecter                      matlab.ui.control.Button
        CurrentFolderEditFieldLabel   matlab.ui.control.Label
        CurrentFolder                 matlab.ui.control.EditField
        Explanation                   matlab.ui.control.Label
        TotalBuildersEditFieldLabel   matlab.ui.control.Label
        TotalBuilders                 matlab.ui.control.NumericEditField
        EditBuilderLabel              matlab.ui.control.Label
        SelectBuilder                 matlab.ui.control.DropDown
        EditBuilderPanel              matlab.ui.container.Panel
        BuilderNameEditFieldLabel     matlab.ui.control.Label
        BuilderNameEdit               matlab.ui.control.EditField
        SplitsTable                   matlab.ui.control.Table
        AddAbove                      matlab.ui.control.Button
        AddBelow                      matlab.ui.control.Button
        Delete                        matlab.ui.control.Button
        BuilderDescriptionTextAreaLabel  matlab.ui.control.Label
        builderDescription            matlab.ui.control.TextArea
        LoadREADMEButton              matlab.ui.control.Button
        AllTimersTab                  matlab.ui.container.Tab
        AllTimers                     matlab.ui.control.Table
        SAVEButton                    matlab.ui.control.Button
        YourtimingsetwillbecreatedinEditFieldLabel  matlab.ui.control.Label
        saveLocation                  matlab.ui.control.EditField
        SequenceDescriptionusemarkdownformattingLabel  matlab.ui.control.Label
        seqDesc                       matlab.ui.control.TextArea
        PushtoGithubButton            matlab.ui.control.Button
        RequiresmanualloginthroughMATLABcommandwindowLabel  matlab.ui.control.Label
        SequenceNameEditFieldLabel    matlab.ui.control.Label
        SeqName                       matlab.ui.control.EditField
        SplitsTab                     matlab.ui.container.Tab
        ChooseSequence                matlab.ui.control.Button
        FolderNameEditFieldLabel      matlab.ui.control.Label
        FolderName                    matlab.ui.control.EditField
        ExplainText                   matlab.ui.control.Label
        SequenceNameEditFieldLabel_2  matlab.ui.control.Label
        SeqyName                      matlab.ui.control.EditField
        BuilderDropDownLabel          matlab.ui.control.Label
        selectTimer                   matlab.ui.control.DropDown
        Times                         matlab.ui.control.Table
        Starto                        matlab.ui.control.Button
        pause                         matlab.ui.control.Button
        push                          matlab.ui.control.Button
        recommit                      matlab.ui.control.Button
        recomText                     matlab.ui.control.Label
        CurrentDateEditFieldLabel     matlab.ui.control.Label
        date                          matlab.ui.control.EditField
        StartTimeEditFieldLabel       matlab.ui.control.Label
        startTime                     matlab.ui.control.EditField
        SequenceDescriptionLabel      matlab.ui.control.Label
        SeqDescRead                   matlab.ui.control.TextArea
        BuilderDescriptionLabel       matlab.ui.control.Label
        BuilderDescRead               matlab.ui.control.TextArea
        NotesEditFieldLabel           matlab.ui.control.Label
        notes                         matlab.ui.control.EditField
        OutputSetupTab                matlab.ui.container.Tab
        ChooseSequenceOut             matlab.ui.control.Button
        FolderNameEditFieldLabel_2    matlab.ui.control.Label
        FolderNameOut                 matlab.ui.control.EditField
        SequenceNameEditFieldLabel_3  matlab.ui.control.Label
        SeqyNameOut                   matlab.ui.control.EditField
        outputInstructions            matlab.ui.control.Label
        SequenceDescriptionLabel_2    matlab.ui.control.Label
        SeqDescReadOut                matlab.ui.control.TextArea
        OutputsTab                    matlab.ui.container.Tab
        outputAllTimes                matlab.ui.control.Table
        showRow                       matlab.ui.control.Table
        ColEdits                      matlab.ui.container.Panel
        ColumnNameEditFieldLabel      matlab.ui.control.Label
        colName                       matlab.ui.control.EditField
        TypeDropDownLabel             matlab.ui.control.Label
        typeSelector                  matlab.ui.control.DropDown
        dataInputs                    matlab.ui.control.Table
        oAddRow                       matlab.ui.control.Button
        oDeleteRow                    matlab.ui.control.Button
        Include                       matlab.ui.control.Button
        UseZero                       matlab.ui.control.Button
    end


    properties (Access = private)
        readmeLocal % readme file location
        readmeFID %FID for readme
        createBuilderNum % what num is active builder?
        path %path to folder
        createInd %last selected splits creation cell
        builderDesc = {'' '' '' ''}; %builder descriptions
        pathT % path to timer file
        timeBuilderNum %what num is active timing builder
        timerStage = 0; %is the timer running? what split is it on?
        pauseTime = 0; %time that's been paused
        dateNtime %date and time of start
        isPaused = false; %are we paused?
        lastPause %when was the last pause?
        pathOutput %path to output file
        outSel %which time to include
        outIncl %where to include selected time
        outBuilders %total number of builders
        outForm %cell array of everything for formatting
        colSelect %which rowshow column are we looking at?
    end

    properties (Access = public)
        Splits = {cell(1) cell(1) cell(1) cell(1)}; % Cell of cells of splits to do 
        allSplits %cell of splits for timing
        outSplits %cell of splits for outputs
        timeSplits %splits in {1}, times in {2}
    end

    methods (Access = private)
    
        function [] = ReaderManip(app, state)
            %ReaderManip Mess around with the stuff in readme
            if(strcmp(state, 'read'))
                cd(app.path); %need to be local
                fid = fopen([app.path 'README.md'], 'r');
                line = fgetl(fid);
                header = strsplit(line, '# ');
                app.SeqName.Value = header{end};
                fgetl(fid);%skip over the double spaces
                line = fgetl(fid);
                passed = 0; %flag for description
                desc = [];
                numBuilders = 1; %which spot is it at
                while all(line ~= -1) || isempty(line) %loop to get data
                    if(strcmp(line, '### Below are the builder and splits settings'))
                        passed = 1;
                        line = fgetl(fid);
                        continue; %no need to do stuff with this line
                    end %if below line
                    if(passed == 0)
                        if(isempty(desc))
                            desc = line; %no line before first line
                        else
                            desc = [desc newline line]; %concat thingy
                        end
                    else
                        if(~isempty(line)) %skip over double spaces
                            parts = strsplit(line,': '); %get buildername
                            builderName{numBuilders} = parts{1};
                            splits = strsplit(parts{2},', '); %get splits
                            app.Splits{numBuilders} = splits; %store into cell
                            numBuilders = numBuilders+1; %increment
                        end %if
                    end %if passed
                    line = fgetl(fid);
                end %while loop
                app.TotalBuilders.Value = numBuilders-1; %set total and account for last round
                app.Splits = app.Splits(1:numBuilders-1); %only the new stuff
                app.SelectBuilder.Items = builderName; %assign to dropdown
                app.SelectBuilder.Value = builderName{1}; %set to first
                app.BuilderNameEdit.Value = app.SelectBuilder.Value; %assignment
                app.SplitsTable.Data = app.Splits{1}'; %set to first
                app.seqDesc.Value = desc;
                previewSplits(app);
            elseif(strcmp(state, 'write'))
                fid = fopen([app.path 'README.md'], 'w');
                fprintf(fid, '# %s', app.SeqName.Value); %title
                fprintf(fid, newline); fprintf(fid, newline); %spaces
                sequenceDescription = textBoxes(app,app.seqDesc.Value);
                fprintf(fid, sequenceDescription);
                fprintf(fid, newline); fprintf(fid, newline);
                fprintf(fid, '### Below are the builder and splits settings'); %separator
                fprintf(fid, newline); fprintf(fid, newline);
                for indB = 1:app.TotalBuilders.Value %for every builder
                    fprintf(fid, '%s: ',app.SelectBuilder.Items{indB});
                    for indSp = 1:numel(app.Splits{indB})
                        if(indSp == numel(app.Splits{indB}))%last thing, no comaa
                            fprintf(fid, app.Splits{indB}{indSp});
                            fprintf(fid, newline); fprintf(fid, newline);
                        else
                            fprintf(fid, '%s, ',app.Splits{indB}{indSp});
                        end %if
                    end %for splits
                end
                
                for indEx = 1:app.TotalBuilders.Value %for every builder
                    filename = sprintf('%s.md',app.SelectBuilder.Items{indEx});
                    fidS = fopen(filename,'w'); %write from beginning
                    header = [{'Date' 'Time'} app.Splits{indEx} {'Total Time' 'Notes'}];
                    fprintf(fidS,'# %s Splits \n\n',app.SelectBuilder.Items{indEx});
                    fprintf(fidS,'%s\n\n',app.builderDesc{indEx}); %print description
                    fprintf(fidS,'### Below are the splits and times\n\n|'); %separator
                    loops = numel(header); %how many times
                    sep = '|'; %start separator line
                    for indSpli = 1:loops
                        fprintf(fidS,'%s|',header{indSpli}); %print header part
                        sep = [sep '---|']; %make separator
                    end %for splits printing
                    fprintf(fidS, newline);
                    fprintf(fidS,sep); %print separator
                end
                !git add .
                message = sprintf('"creating sequence: %s"',app.SeqName.Value);
                system(['git commit -m ' message]);
            elseif(strcmp(state, 'time'))
                fid = fopen([app.pathT 'README.md'],'r');
                line = fgetl(fid);
                header = strsplit(line, '# ');
                app.SeqyName.Value = header{end};
                fgetl(fid);%skip over the double spaces
                line = fgetl(fid);
                passed = 0; %flag for description
                desc = [];
                numBuilders = 1; %which spot is it at
                while all(line ~= -1) || isempty(line) %loop to get data
                    if(strcmp(line, '### Below are the builder and splits settings'))
                        passed = 1;
                        line = fgetl(fid);
                        continue; %no need to do stuff with this line
                    end %if below line
                    if(passed == 0)
                        if(isempty(desc))
                            desc = line; %no line before first line
                        else
                            desc = [desc newline line]; %concat thingy
                        end
                    else
                        if(~isempty(line)) %skip over double spaces
                            parts = strsplit(line,': '); %get buildername
                            builderName{numBuilders} = parts{1};
                            splits = strsplit(parts{2},', '); %get splits
                            app.allSplits{numBuilders} = [splits {'Total'}]; %store into cell
                            numBuilders = numBuilders+1; %increment
                        end %if
                    end %if passed
                    line = fgetl(fid);
                end %while loop
                app.allSplits = app.allSplits(1:numBuilders-1); %only the new stuff
                app.selectTimer.Items = builderName; %assign to dropdown
                app.selectTimer.Value = builderName{1}; %set to first
                app.Times.Data = [app.allSplits{1}' cell(numel(app.allSplits{1}),1)]; %set to first
                app.SeqDescRead.Value = desc;
                folderParts = strsplit(app.pathT,'/'); %for getting foldername
                app.FolderName.Value = folderParts{end-1};
                totalRows = max(cellfun(@numel,app.Splits)); %how many rows in total
                data = cell(totalRows,app.TotalBuilders.Value); %pre allocate
                for indCol = 1:app.TotalBuilders.Value %for every column
                    if(numel(app.Splits{indCol}) ~= totalRows) %too few
                        data(:,indCol) = [app.Splits{indCol} cell(1,totalRows - ...
                            numel(app.Splits{indCol}))]';
                    else
                        data(:,indCol) = app.Splits{indCol}'; %gotta transpose
                    end %if
                end %for
                app.AllTimers.Data = data;
            elseif(strcmp(state, 'output'))
                fid = fopen([app.pathOutput 'README.md'],'r');
                line = fgetl(fid);
                header = strsplit(line, '# ');
                app.SeqyNameOut.Value = header{end};
                fgetl(fid);%skip over the double spaces
                line = fgetl(fid);
                passed = 0; %flag for description
                desc = [];
                numBuilders = 1; %which spot is it at
                while all(line ~= -1) || isempty(line) %loop to get data
                    if(strcmp(line, '### Below are the builder and splits settings'))
                        passed = 1;
                        line = fgetl(fid);
                        continue; %no need to do stuff with this line
                    end %if below line
                    if(passed == 0)
                        if(isempty(desc))
                            desc = line; %no line before first line
                        else
                            desc = [desc newline line]; %concat thingy
                        end
                    else
                        if(~isempty(line)) %skip over double spaces
                            parts = strsplit(line,': '); %get buildername
                            builderName{numBuilders} = parts{1};
                            splits = strsplit(parts{2},', '); %get splits
                            app.outSplits{numBuilders} = [splits {'Total'}]; %store into cell
                            numBuilders = numBuilders+1; %increment
                        end %if
                    end %if passed
                    line = fgetl(fid);
                end %while loop
                app.SeqDescRead.Value = desc;
                folderParts = strsplit(app.pathOutput,'/'); %for getting foldername
                app.FolderNameOut.Value = folderParts{end-1};
                app.outputAllTimes.ColumnName = builderName; %assign in names
                
                totalRows = max(cellfun(@numel,app.outSplits)); %how many rows in total
                data = cell(totalRows,numBuilders); %pre allocate
                for indCol = 1:numBuilders-1 %for every column
                    if(numel(app.outSplits{indCol}) ~= totalRows) %too few
                        data(:,indCol) = [app.outSplits{indCol} cell(1,totalRows - ...
                            numel(app.outSplits{indCol}))]';
                    else
                        data(:,indCol) = app.outSplits{indCol}'; %gotta transpose
                    end %if
                end %for
                app.outBuilders = numBuilders - 1; %total builders
                app.outputAllTimes.Data = data;
            elseif(strcmp(state,'builderRead'))
                %get description
                filename = sprintf('%s.md',app.selectTimer.Items{app.timeBuilderNum});
                fidB = fopen(filename);
                line = fgetl(fidB);
                fgetl(fidB);%skip over the double spaces
                line = fgetl(fidB);
                passed = 0; %flag for description
                desc = [];
                while ~passed %loop to get data
                    if(strcmp(line, '### Below are the splits and times'))
                        passed = 1;
                        line = fgetl(fidB);
                        continue; %no need to do stuff with this line
                    end %if below line
                    if(isempty(desc))
                        desc = line; %no line before first line
                    else
                        desc = [desc newline line]; %concat thingy
                    end
                    line = fgetl(fidB);
                end %while
                app.BuilderDescRead.Value = desc;
            elseif(strcmp(state, 'builderWrite'))
                %auto save after stopping
                filename = sprintf('%s.md',app.selectTimer.Items{app.timeBuilderNum});
                fidMD = fopen(filename, 'a');
                fprintf(fidMD,'\n|'); %start with separator
                fprintf(fidMD,' %s |',app.date.Value);
                fprintf(fidMD,' %s |',app.startTime.Value);
                for indP = 1:size(app.Times.Data,1) %all rows
                    fprintf(fidMD,' %.3f |',app.Times.Data{indP,2});
                end %for printing times
                fprintf(fidMD,' %s |', app.notes.Value);
                fclose(fidMD);
                system(['git add ' sprintf('"%s"',filename)]); %only 1 file
                message = sprintf('"splits of %s at %s"',app.selectTimer.Items{...
                    app.timeBuilderNum},app.startTime.Value);
                system(['git commit -m ' message]);
            elseif(strcmp(state,'builderRewrite'))
                filename = sprintf('%s.md',app.selectTimer.Items{app.timeBuilderNum});
                fidRw = fopen(filename, 'r+');
                line = fgetl(fidRw);
                text = {};
                while(all(line ~= -1))
                    text{end+1} = line;
                    line = fgetl(fidRw);
                end %while
                offset = -numel(text{end}); %offset for value
                fseek(fidRw,offset,'eof'); %get to right spot
                fprintf(fidRw,'|'); %start with separator
                fprintf(fidRw,' %s |',app.date.Value);
                fprintf(fidRw,' %s |',app.startTime.Value);
                for indP = 1:size(app.Times.Data,1) %all rows
                    fprintf(fidRw,' %.3f |',app.Times.Data{indP,2});
                end %for printing times
                fprintf(fidRw,' %s |', app.notes.Value);
                fclose(fidRw);
                
                system(['git add ' sprintf('"%s"',filename)]); %only 1 file
                message = sprintf('"editing splits of %s at %s"',app.selectTimer.Items{...
                    app.timeBuilderNum},app.startTime.Value);
                system(['git commit -m ' message]);
            end %if state
        end
        
        function [] = previewSplits(app)
            %previewSplits Update the table with all the split information
            app.AllTimers.ColumnName = app.SelectBuilder.Items; %cols
            totalRows = max(cellfun(@numel,app.Splits)); %how many rows in total
            data = cell(totalRows,app.TotalBuilders.Value); %pre allocate
            for indCol = 1:app.TotalBuilders.Value %for every column
                if(numel(app.Splits{indCol}) ~= totalRows) %too few
                    data(:,indCol) = [app.Splits{indCol} cell(1,totalRows - ...
                        numel(app.Splits{indCol}))]';
                else
                    data(:,indCol) = app.Splits{indCol}'; %gotta transpose
                end %if
            end %for
            app.AllTimers.Data = data;
                        
        end
        
        function [paragraph] = textBoxes(app,rawCells)
           %texBoxes Get text from Text Areas out of cells
           total = numel(rawCells); %how many cells
           paragraph = rawCells{1};%initial cell value
           if(total>1)
               for indRow = 2:total
                   paragraph = [paragraph newline rawCells{indRow}];
               end %for rows
           end %if more than 1
        end
    
        function [timeStr] = sec2Min(app, secs)
            %sec2Min convert seconds to Minutes:seconds.milliseconds format
            %maybe there's a built in command, but that would require using actual
            %time formatting, and I just don't want to.
            if(secs < 60) %under a minute
                timeStr = sprintf('%06.3f',secs); %write it straight
            elseif(secs < 3600) %less than an hour
                seconds = rem(secs,60);
                rawMin = secs - seconds; %get raw time
                minutes = rawMin/60; %get total minutes
                timeStr = sprintf('%d:%06.3f',minutes,seconds); %print it out
            else %more than an hour
                unCouth = rem(secs,3600); %for hours
                rawHr = secs - unCouth;
                hours = rawHr/3600;
                seconds = rem(unCouth,60); %for minutes
                rawMin = unCouth - seconds;
                minutes = rawMin/60;
                timeStr = sprtinf('%d:%d:%06.3f',hours, minutes,seconds);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BuilderNameEdit.Value = app.SelectBuilder.Value; %assignment
            app.SplitsTable.Data = cell(1);
            app.createBuilderNum = find(contains(app.SelectBuilder.Items, ...
                app.SelectBuilder.Value));
            previewSplits(app); %make preview
        end

        % Button pushed function: Selecter
        function SelecterButtonPushed(app, event)
            [f,app.path] = uiputfile('README.md'); %get file location
            if(f ~= 0)
                cd(app.path); %be in the right spot
                app.readmeLocal = [app.path f]; %concat
                app.readmeFID = fopen(app.readmeLocal, 'w'); %w/ writing
                parts = strsplit(app.path, '/'); %for folder name
                app.CurrentFolder.Value = parts{end-1}; %bottom folder
                app.saveLocation.Value = app.path;
            end %if error
        end

        % Close request function: Timer
        function TimerCloseRequest(app, event)
            fclose all; %be safe fam
            delete(app)
            
        end

        % Value changed function: TotalBuilders
        function TotalBuildersValueChanged(app, event)
            value = app.TotalBuilders.Value;
            opts = app.SelectBuilder.Items;
            existing = numel(opts); %how many already things
            if(existing > value) %more than total builders
                opts = opts(1:value);
                app.Splits = app.Splits(1:value); %manage data
                app.builderDesc = app.builderDesc{1:value};
            elseif(existing < value) %need to add more things
                for indOpts = existing+1:value
                    opts{indOpts} = sprintf('Builder %d',indOpts);
                    app.Splits{indOpts} = cell(1);
                    app.builderDesc{indOpts} = '';
                end %for
            end
            app.SelectBuilder.Items = opts; %put that back in
            previewSplits(app); %update preview
        end

        % Value changed function: SelectBuilder
        function SelectBuilderValueChanged(app, event)
            name = app.SelectBuilder.Value;
            app.BuilderNameEdit.Value = name; %add to name
            app.createBuilderNum = find(contains(app.SelectBuilder.Items, name));
            app.SplitsTable.Data = app.Splits{app.createBuilderNum}';
            app.builderDescription.Value = app.builderDesc{app.createBuilderNum};
        end

        % Value changed function: BuilderNameEdit
        function BuilderNameEditValueChanged(app, event)
            newName = app.BuilderNameEdit.Value;
            app.SelectBuilder.Items{app.createBuilderNum} = newName;
            app.SelectBuilder.Value = newName;
            previewSplits(app); %update preview
        end

        % Button pushed function: LoadREADMEButton
        function LoadREADMEButtonPushed(app, event)
            [f,app.path] = uigetfile('README.md'); %get file location
            if(f ~= 0)
                cd(app.path); %get to the right spot for other things
                app.readmeLocal = [app.path f]; %concat
                app.readmeFID = fopen(app.readmeLocal, 'r'); %w/ reading only
                parts = strsplit(app.path,'/'); %for folder name
                app.CurrentFolder.Value = parts{end-1}; %bottom folder
                app.saveLocation.Value = app.path;
            end %if
         %   try
                ReaderManip(app, 'read');
%             catch help
%                 uialert(app.Timer,'File preset not loaded correctly, please try again','Error');
%             end
        end

        % Button pushed function: AddAbove
        function AddAboveButtonPushed(app, event)
            try
                data = app.SplitsTable.Data; %pull it out
                newRow = cell(1); %to size
                data = [data(1:app.createInd(1)-1)' newRow data(app.createInd(1):end)'];
                app.SplitsTable.Data = data'; %transpose
                app.Splits{app.createBuilderNum} = data; %fill in universal thingy
            catch helper
                uialert(app.Timer, 'Please try reselecting a cell', 'Split Editing');
            end
            previewSplits(app); %update preview
        end

        % Cell selection callback: SplitsTable
        function SplitsTableCellSelection(app, event)
            app.createInd = event.Indices;
        end

        % Button pushed function: AddBelow
        function AddBelowButtonPushed(app, event)
            try
                data = app.SplitsTable.Data; %pull it out
                newRow = cell(1); %to size
                data = [data(1:app.createInd(1))' newRow data(app.createInd(1)+1:end)'];
                app.SplitsTable.Data = data'; %transpose
                app.Splits{app.createBuilderNum} = data; %fill in universal thingy
            catch
                uialert(app.Timer, 'Please try reselecting a cell', 'Split Editing');
            end
            previewSplits(app); %update preview
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            try
                data = app.SplitsTable.Data; %pull it out
                data = [data(1:app.createInd(1)-1)' data(app.createInd(1)+1:end)'];
                app.SplitsTable.Data = data'; %transpose
                app.Splits{app.createBuilderNum} = data; %fill in universal thingy
            catch
                uialert(app.Timer, 'Please try reselecting a cell', 'Split Editing');
            end
            previewSplits(app); %update preview
        end

        % Cell edit callback: SplitsTable
        function SplitsTableCellEdit(app, event)
            app.Splits{app.createBuilderNum} = app.SplitsTable.Data';
            previewSplits(app); %update preview
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            ReaderManip(app, 'write');
        end

        % Value changed function: builderDescription
        function builderDescriptionValueChanged(app, event)
            text = textBoxes(app,app.builderDescription.Value);
            app.builderDesc{app.createBuilderNum} = text;
            %set new value into cell
        end

        % Button pushed function: PushtoGithubButton
        function PushtoGithubButtonPushed(app, event)
            !git push
        end

        % Button pushed function: ChooseSequence
        function ChooseSequenceButtonPushed(app, event)
            [f,app.pathT] = uigetfile('README.md'); %get the file
            if(f ~= 0) %uncancelled
                cd(app.pathT); %get in thur
                ReaderManip(app,'time')
                app.timeBuilderNum = 1; %intialize
                ReaderManip(app,'builderRead');
            end %if cancel
                
        end

        % Value changed function: selectTimer
        function selectTimerValueChanged(app, event)
            name = app.selectTimer.Value;
            app.timeBuilderNum = find(contains(app.selectTimer.Items, name));
            app.Times.Data = app.allSplits{app.createBuilderNum}';
            ReaderManip(app, 'builderRead'); %get updated description
            extraCell = cell(numel(app.allSplits{app.timeBuilderNum}),1);
            app.Times.Data = [app.allSplits{app.timeBuilderNum}' extraCell];
        end

        % Button pushed function: Starto
        function StartoButtonPushed(app, event)
            currSplits = app.allSplits{app.timeBuilderNum}; %relevant splits
            if(~app.isPaused)
                if(app.timerStage == 0) %not running
                    tic
                    app.dateNtime = datetime; %set current datetime
                    app.date.Value = datestr(app.dateNtime, 'mm-dd-yyyy');
                    app.startTime.Value = datestr(app.dateNtime, 'HH:MM:SS');
                    app.timerStage = 1; %we are now timing!
                    app.Starto.Text = currSplits{app.timerStage};
                    app.Starto.BackgroundColor = 'cyan'; %set color
                    app.Times.ColumnEditable = logical([0 0]); %no editing allowed
                    app.Times.Data(:,2) = cell(numel(currSplits,1));
                    app.pause.Enable = 'on'; %go for launch
                    app.pause.BackgroundColor = 'yellow';
                    app.notes.Value = ''; %clear notes
                    app.pauseTime = 0; %reset back to zero
                elseif(app.timerStage > 0 && app.timerStage < numel(currSplits)-1)
                    newtime = toc; %get time
                    app.Times.Data{app.timerStage,2} = sec2Min(app,newtime - app.pauseTime); %put it in
                    app.timerStage = app.timerStage+1; %increment
                    app.Starto.Text = currSplits{app.timerStage};
                elseif(app.timerStage == numel(currSplits)-1) %second to last 
                    newtime = toc; %get time
                    app.Times.Data{app.timerStage,2} = sec2Min(app,newtime - app.pauseTime); %put it in
                    app.Starto.Text = sprintf('Final Time\nStop');
                    app.Starto.BackgroundColor = 'red';
                    app.timerStage = app.timerStage + 1;
                elseif(app.timerStage == numel(currSplits)) %timer is done
                    newtime = toc; %get time
                    app.Times.Data{app.timerStage,2} = sec2Min(app,newtime - app.pauseTime); %put it in
                    app.Starto.Text = 'Start!';
                    app.Starto.BackgroundColor = 'green';
                    ReaderManip(app,'builderWrite'); %write to file
                    app.Times.ColumnEditable = logical([0 1]); %can edit times
                    app.timerStage = 0; %reset for next time
                    app.pause.Enable = 'off'; %no pausing allowed
                    app.pause.BackgroundColor = [.94 .94 .94]; %default color
                end %end if stage
            else
                newTime = toc;
                pauseDur = newTime - app.lastPause; %how long were we paused
                app.pauseTime = app.pauseTime + pauseDur;
                app.Starto.Text = currSplits{app.timerStage};
                app.Starto.BackgroundColor = 'cyan'; %set color
                app.pause.Enable = 'on'; %go for relaunch
                app.pause.BackgroundColor = 'yellow';
                app.isPaused = false;
            end %if paused
        end

        % Button pushed function: pause
        function pauseButtonPushed(app, event)
            app.lastPause = toc; %set time of pause
            app.pause.Enable = 'off'; %can't double pause
            app.pause.BackgroundColor = [.94 .94 .94]; %default color
            app.Starto.Text = 'Resume';
            app.Starto.BackgroundColor = 'yellow';
            app.isPaused = true; %we are now paused
        end

        % Button pushed function: recommit
        function recommitButtonPushed(app, event)
            ReaderManip(app,'builderRewrite');
        end

        % Button pushed function: push
        function pushButtonPushed(app, event)
            system('git push'); %pushy pushy
        end

        % Button pushed function: ChooseSequenceOut
        function ChooseSequenceOutButtonPushed(app, event)
            [f,app.pathOutput] = uigetfile('README.md'); %get the file
            if(f ~= 0) %uncancelled
                cd(app.pathOutput); %get in thur
                ReaderManip(app,'output')
                app.showRow.Data = {'Date' 'Time'; 'Locked' 'Locked'}; %preset
%                 app.timeBuilderNum = 1; %intialize
%                 ReaderManip(app,'builderRead');
            end %if cancel
        end

        % Cell selection callback: outputAllTimes
        function outputAllTimesCellSelection(app, event)
            app.outSel = event.Indices; %save where we clicked
            app.Include.Enable = 'on'; %turn them on, we have some value
            app.UseZero.Enable = 'on';
        end

        % Cell selection callback: dataInputs
        function dataInputsCellSelection(app, event)
            app.outIncl = event.Indices; %save where we clicked
            app.Include.Enable = 'on'; %turn them on, we have some value
            app.UseZero.Enable = 'on';
        end

        % Cell selection callback: showRow
        function showRowCellSelection(app, event)
            ind = event.Indices;
            if(ind(2) > 2) %outside time/date
                app.colSelect = ind; %save for adding
                local = ind(2) - 2;
                app.colName.Value = app.outForm{1,local};
                app.typeSelector.Value = app.outForm{2,local};
                app.dataInputs
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Timer = uifigure;
            app.Timer.Name = 'UI Figure';
            app.Timer.CloseRequestFcn = createCallbackFcn(app, @TimerCloseRequest, true);
            app.Tabs = uitabgroup(app.Timer);
            app.Create = uitab(app.Tabs);
            app.Create.Title = 'Create Timers';
            app.Selecter = uibutton(app.Create, 'push');
            app.Selecter.ButtonPushedFcn = createCallbackFcn(app, @SelecterButtonPushed, true);
            app.Selecter.Text = 'Create README';
            app.CurrentFolderEditFieldLabel = uilabel(app.Create);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder';
            app.CurrentFolder = uieditfield(app.Create, 'text');
            app.CurrentFolder.Editable = 'off';
            app.Explanation = uilabel(app.Create);
            app.Explanation.Text = {'Please create a README file within the folder for your sequence.  Create one folder per '; 'sequence, with the title ''README.md'' as the filename.  Name your folder for the sequence.'; 'Alternatively, load a pre-created README to edit splits.  Re-saving will delete old times!  '; 'To make modifications after recording times, create a new folder!'};
            app.TotalBuildersEditFieldLabel = uilabel(app.Create);
            app.TotalBuildersEditFieldLabel.Text = 'Total Builders';
            app.TotalBuilders = uieditfield(app.Create, 'numeric');
            app.TotalBuilders.Limits = [0 Inf];
            app.TotalBuilders.ValueDisplayFormat = '%.0f';
            app.TotalBuilders.ValueChangedFcn = createCallbackFcn(app, @TotalBuildersValueChanged, true);
            app.TotalBuilders.Value = 4;
            app.EditBuilderLabel = uilabel(app.Create);
            app.EditBuilderLabel.Text = 'Edit Builder:';
            app.SelectBuilder = uidropdown(app.Create);
            app.SelectBuilder.Items = {'Builder 1', 'Builder 2', 'Builder 3', 'Builder 4'};
            app.SelectBuilder.Editable = 'on';
            app.SelectBuilder.ValueChangedFcn = createCallbackFcn(app, @SelectBuilderValueChanged, true);
            app.SelectBuilder.Value = 'Builder 1';
            app.EditBuilderPanel = uipanel(app.Create);
            app.EditBuilderPanel.Title = 'Edit Builder';
            app.BuilderNameEditFieldLabel = uilabel(app.EditBuilderPanel);
            app.BuilderNameEditFieldLabel.Text = 'Builder Name';
            app.BuilderNameEdit = uieditfield(app.EditBuilderPanel, 'text');
            app.BuilderNameEdit.ValueChangedFcn = createCallbackFcn(app, @BuilderNameEditValueChanged, true);
            app.SplitsTable = uitable(app.EditBuilderPanel);
            app.SplitsTable.ColumnName = {'Splits'};
            app.SplitsTable.RowName = {};
            app.SplitsTable.ColumnEditable = true;
            app.SplitsTable.CellEditCallback = createCallbackFcn(app, @SplitsTableCellEdit, true);
            app.SplitsTable.CellSelectionCallback = createCallbackFcn(app, @SplitsTableCellSelection, true);
            app.AddAbove = uibutton(app.EditBuilderPanel, 'push');
            app.AddAbove.ButtonPushedFcn = createCallbackFcn(app, @AddAboveButtonPushed, true);
            app.AddAbove.Text = 'Add Split Above';
            app.AddBelow = uibutton(app.EditBuilderPanel, 'push');
            app.AddBelow.ButtonPushedFcn = createCallbackFcn(app, @AddBelowButtonPushed, true);
            app.AddBelow.Text = 'Add Split Below';
            app.Delete = uibutton(app.EditBuilderPanel, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete Split';
            app.BuilderDescriptionTextAreaLabel = uilabel(app.EditBuilderPanel);
            app.BuilderDescriptionTextAreaLabel.Text = 'Builder Description';
            app.builderDescription = uitextarea(app.EditBuilderPanel);
            app.builderDescription.ValueChangedFcn = createCallbackFcn(app, @builderDescriptionValueChanged, true);
            app.LoadREADMEButton = uibutton(app.Create, 'push');
            app.LoadREADMEButton.ButtonPushedFcn = createCallbackFcn(app, @LoadREADMEButtonPushed, true);
            app.LoadREADMEButton.Text = 'Load README';
            app.AllTimersTab = uitab(app.Tabs);
            app.AllTimersTab.Title = 'All Timers';
            app.AllTimers = uitable(app.AllTimersTab);
            app.AllTimers.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AllTimers.RowName = {};
            app.SAVEButton = uibutton(app.AllTimersTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE!';
            app.YourtimingsetwillbecreatedinEditFieldLabel = uilabel(app.AllTimersTab);
            app.YourtimingsetwillbecreatedinEditFieldLabel.Text = 'Your timing set will be created in:';
            app.saveLocation = uieditfield(app.AllTimersTab, 'text');
            app.saveLocation.Editable = 'off';
            app.SequenceDescriptionusemarkdownformattingLabel = uilabel(app.AllTimersTab);
            app.SequenceDescriptionusemarkdownformattingLabel.Text = {'Sequence '; 'Description '; '(use markdown '; 'formatting)'};
            app.seqDesc = uitextarea(app.AllTimersTab);
            app.PushtoGithubButton = uibutton(app.AllTimersTab, 'push');
            app.PushtoGithubButton.ButtonPushedFcn = createCallbackFcn(app, @PushtoGithubButtonPushed, true);
            app.PushtoGithubButton.Text = 'Push to Github';
            app.RequiresmanualloginthroughMATLABcommandwindowLabel = uilabel(app.AllTimersTab);
            app.RequiresmanualloginthroughMATLABcommandwindowLabel.Text = {'(Requires manual login'; 'through MATLAB '; 'command window)'};
            app.SequenceNameEditFieldLabel = uilabel(app.AllTimersTab);
            app.SequenceNameEditFieldLabel.Text = 'Sequence Name';
            app.SeqName = uieditfield(app.AllTimersTab, 'text');
            app.SplitsTab = uitab(app.Tabs);
            app.SplitsTab.Title = 'Splits';
            app.ChooseSequence = uibutton(app.SplitsTab, 'push');
            app.ChooseSequence.ButtonPushedFcn = createCallbackFcn(app, @ChooseSequenceButtonPushed, true);
            app.ChooseSequence.Text = 'Select Sequence README';
            app.FolderNameEditFieldLabel = uilabel(app.SplitsTab);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderName = uieditfield(app.SplitsTab, 'text');
            app.FolderName.Editable = 'off';
            app.ExplainText = uilabel(app.SplitsTab);
            app.ExplainText.Text = 'Select the folder that contains the README and builder files';
            app.SequenceNameEditFieldLabel_2 = uilabel(app.SplitsTab);
            app.SequenceNameEditFieldLabel_2.Text = 'Sequence Name';
            app.SeqyName = uieditfield(app.SplitsTab, 'text');
            app.SeqyName.Editable = 'off';
            app.BuilderDropDownLabel = uilabel(app.SplitsTab);
            app.BuilderDropDownLabel.Text = 'Builder';
            app.selectTimer = uidropdown(app.SplitsTab);
            app.selectTimer.ValueChangedFcn = createCallbackFcn(app, @selectTimerValueChanged, true);
            app.Times = uitable(app.SplitsTab);
            app.Times.ColumnName = {'Splits'; 'Time'};
            app.Times.RowName = {};
            app.Starto = uibutton(app.SplitsTab, 'push');
            app.Starto.ButtonPushedFcn = createCallbackFcn(app, @StartoButtonPushed, true);
            app.Starto.Text = 'Start!';
            app.pause = uibutton(app.SplitsTab, 'push');
            app.pause.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pause.Enable = 'off';
            app.pause.Text = 'Pause';
            app.push = uibutton(app.SplitsTab, 'push');
            app.push.ButtonPushedFcn = createCallbackFcn(app, @pushButtonPushed, true);
            app.push.Text = 'Push to Github';
            app.recommit = uibutton(app.SplitsTab, 'push');
            app.recommit.ButtonPushedFcn = createCallbackFcn(app, @recommitButtonPushed, true);
            app.recommit.Text = 'Re-commit';
            app.recomText = uilabel(app.SplitsTab);
            app.recomText.Text = {'Use this button'; 'if you make any '; 'manual changes.'};
            app.CurrentDateEditFieldLabel = uilabel(app.SplitsTab);
            app.CurrentDateEditFieldLabel.Text = 'Current Date';
            app.date = uieditfield(app.SplitsTab, 'text');
            app.date.Editable = 'off';
            app.StartTimeEditFieldLabel = uilabel(app.SplitsTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.startTime = uieditfield(app.SplitsTab, 'text');
            app.startTime.Editable = 'off';
            app.SequenceDescriptionLabel = uilabel(app.SplitsTab);
            app.SequenceDescriptionLabel.Text = 'Sequence Description';
            app.SeqDescRead = uitextarea(app.SplitsTab);
            app.SeqDescRead.Editable = 'off';
            app.BuilderDescriptionLabel = uilabel(app.SplitsTab);
            app.BuilderDescriptionLabel.Text = 'Builder Description';
            app.BuilderDescRead = uitextarea(app.SplitsTab);
            app.BuilderDescRead.Editable = 'off';
            app.NotesEditFieldLabel = uilabel(app.SplitsTab);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.notes = uieditfield(app.SplitsTab, 'text');
            app.OutputSetupTab = uitab(app.Tabs);
            app.OutputSetupTab.Title = 'Output Setup';
            app.ChooseSequenceOut = uibutton(app.OutputSetupTab, 'push');
            app.ChooseSequenceOut.ButtonPushedFcn = createCallbackFcn(app, @ChooseSequenceOutButtonPushed, true);
            app.ChooseSequenceOut.Text = 'Select Sequence README';
            app.FolderNameEditFieldLabel_2 = uilabel(app.OutputSetupTab);
            app.FolderNameEditFieldLabel_2.Text = 'Folder Name';
            app.FolderNameOut = uieditfield(app.OutputSetupTab, 'text');
            app.FolderNameOut.Editable = 'off';
            app.SequenceNameEditFieldLabel_3 = uilabel(app.OutputSetupTab);
            app.SequenceNameEditFieldLabel_3.Text = 'Sequence Name';
            app.SeqyNameOut = uieditfield(app.OutputSetupTab, 'text');
            app.SeqyNameOut.Editable = 'off';
            app.outputInstructions = uilabel(app.OutputSetupTab);
            app.outputInstructions.Text = {'Create output file formats.  Create data sets based on recorded'; 'timing, in both Markdown and Matlab Tables.  Select what kind'; 'of data point (passthrough, average, min, max) as well as what'; 'inputs each point should consider.'};
            app.SequenceDescriptionLabel_2 = uilabel(app.OutputSetupTab);
            app.SequenceDescriptionLabel_2.Text = 'Sequence Description';
            app.SeqDescReadOut = uitextarea(app.OutputSetupTab);
            app.SeqDescReadOut.Editable = 'off';
            app.OutputsTab = uitab(app.Tabs);
            app.OutputsTab.Title = 'Outputs';
            app.outputAllTimes = uitable(app.OutputsTab);
            app.outputAllTimes.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.outputAllTimes.RowName = {};
            app.outputAllTimes.CellSelectionCallback = createCallbackFcn(app, @outputAllTimesCellSelection, true);
            app.showRow = uitable(app.OutputsTab);
            app.showRow.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.showRow.RowName = {};
            app.showRow.CellSelectionCallback = createCallbackFcn(app, @showRowCellSelection, true);
            app.ColEdits = uipanel(app.OutputsTab);
            app.ColEdits.Title = 'Panel';
            app.ColumnNameEditFieldLabel = uilabel(app.ColEdits);
            app.ColumnNameEditFieldLabel.Text = 'Column Name';
            app.colName = uieditfield(app.ColEdits, 'text');
            app.TypeDropDownLabel = uilabel(app.ColEdits);
            app.TypeDropDownLabel.Text = 'Type';
            app.typeSelector = uidropdown(app.ColEdits);
            app.typeSelector.Items = {'Simple', 'Average', 'Minimum', 'Maximum'};
            app.typeSelector.Value = 'Simple';
            app.dataInputs = uitable(app.ColEdits);
            app.dataInputs.ColumnName = {'End Time'; 'Start Time'};
            app.dataInputs.RowName = {};
            app.dataInputs.CellSelectionCallback = createCallbackFcn(app, @dataInputsCellSelection, true);
            app.oAddRow = uibutton(app.OutputsTab, 'push');
            app.oAddRow.Text = 'Add Row';
            app.oDeleteRow = uibutton(app.OutputsTab, 'push');
            app.oDeleteRow.Text = 'Delete Row';
            app.Include = uibutton(app.OutputsTab, 'push');
            app.Include.Enable = 'off';
            app.Include.Text = 'Include Selected';
            app.UseZero = uibutton(app.OutputsTab, 'push');
            app.UseZero.Enable = 'off';
            app.UseZero.Text = 'Use Zero Time';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SBTimer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Timer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Timer)
        end
    end
end